classdef A5_rename_Hengshan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        nameRule     matlab.ui.container.ButtonGroup
        Button1      matlab.ui.control.ToggleButton
        Button2      matlab.ui.control.ToggleButton
        Button3      matlab.ui.control.ToggleButton
        Button4      matlab.ui.control.ToggleButton
        selectDist   matlab.ui.control.Button
        dirPath      matlab.ui.control.Label
        goto_n       matlab.ui.control.Button
        Label        matlab.ui.control.Label
        TextArea     matlab.ui.control.TextArea
        dirName      matlab.ui.control.Label
        dirInfo      matlab.ui.control.Label
        save_last    matlab.ui.control.Button
        save_next    matlab.ui.control.Button
        rotateRule   matlab.ui.container.ButtonGroup
        rotateLeft   matlab.ui.control.ToggleButton
        rotateRight  matlab.ui.control.ToggleButton
        RightPanel   matlab.ui.container.Panel
        GridLayout1  matlab.ui.container.GridLayout
        Image1       matlab.ui.control.Image
        Image2       matlab.ui.control.Image
        Image3       matlab.ui.control.Image
        Image4       matlab.ui.control.Image
        Image5       matlab.ui.control.Image
        Image6       matlab.ui.control.Image
        Image7       matlab.ui.control.Image
        Image8       matlab.ui.control.Image
        Image9       matlab.ui.control.Image
        Image10      matlab.ui.control.Image
        Image11      matlab.ui.control.Image
        Image12      matlab.ui.control.Image
        Image13      matlab.ui.control.Image
        Image14      matlab.ui.control.Image
        Image15      matlab.ui.control.Image
        Image16      matlab.ui.control.Image
        Image17      matlab.ui.control.Image
        Image18      matlab.ui.control.Image
        Image19      matlab.ui.control.Image
        Image20      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        reTitle = '';   % Surface title to retitle
        reTiNo = struct('N1_',1,'N2_',1,'N3_',1,'N4_',1); % 记录重命名次数
        subDir = '';
        No = 1;
        n = 0;
        angle = 0;
    end
    
    methods (Access = private)
        
        function addDist(app,subDir)
            app.dirInfo.Text = ['共' num2str(app.n) '个文件夹，当前第' num2str(app.No) '个']; % 文件信息
            app.dirName.Text = [subDir(app.No).name];% 文件夹名
                
            subDirpath = fullfile(subDir(app.No).folder, subDir(app.No).name, '\');
            imgDir = dir([subDirpath '*.jpg']);
            
%             app.GridLayout1 = uigridlayout(app.RightPanel);
            app.GridLayout1.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};
            app.GridLayout1.RowHeight = {'1x', '1x', '1x'}; % 重置网格
            
            app.addImage(imgDir);
            
        end
        
        function addImage(app, imgDir)
            % 动态加载图片
            figureWait = waitbar(0,'加载图像中...');
            for j = 1:length(imgDir) % 遍历结构体就可以一一处理图片了
                waitbar(j/length(imgDir), figureWait, '加载图像中...');
                
                % 压缩图片
                OrigImg = imread(fullfile(imgDir(j).folder,imgDir(j).name));
                NewImg = imresize(OrigImg,[480,480]);
                
                % 更新图片属性
                app.GridLayout1.Children(j).Visible = 'on';
                app.GridLayout1.Children(j).ImageSource = NewImg;
                app.GridLayout1.Children(j).Tag = imgDir(j).name;
                app.GridLayout1.Children(j).Tooltip = imgDir(j).name;
            end
            close(figureWait);
        end
        
        function reName(app, imgObj)
            if strlength(app.reTitle) > 0
                oldname = fullfile(app.dirPath.Text,app.dirName.Text,imgObj.Tag);
            
                % 截取reTitle，作为字段
                strField = extractAfter(app.reTitle,length(app.reTitle)-1);
                newname = [app.reTitle '_' num2str(app.reTiNo.(['N' strField '_'])) '.jpg'];
                
                % 系统函数 重命名rename
                system(['RENAME ' 32 ,'"',oldname,'"',32 newname]);
                imgObj.Tooltip = [app.reTitle '_' num2str(app.reTiNo.(['N' strField '_']))];
                
                app.reTiNo.(['N' strField '_']) = app.reTiNo.(['N' strField '_']) + 1;
            end
        end
        
        function reRotate(app, imgObj)
            if app.angle > 0
                OrigImg = imread(fullfile(app.dirPath.Text,app.dirName.Text,imgObj.Tag));
                OrigImg = imrotate(OrigImg, app.angle);
                
                % 缩略图旋转
                smallImg = imresize(OrigImg,[480,480]);
                % 更新图片属性
                for i = 1:length(app.GridLayout1.Children)
                    if strcmp(app.GridLayout1.Children(i).Tag, imgObj.Tag)
                        app.GridLayout1.Children(i).ImageSource = smallImg;
                        break;
                    end
                end
                imwrite(OrigImg, fullfile(app.dirPath.Text,app.dirName.Text,imgObj.Tag));
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {737, 737};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {213, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: nameRule
        function updateName(app, event)
            selectedButton = app.nameRule.SelectedObject;
            % 更新命名规则
            app.reTitle = selectedButton.Text;
            app.angle = 0;
%             uiconfirm(app.UIFigure, ['命名规则：' app.reTitle newline '旋转规则：' num2str(app.angle) '°'], '提示', 'Options', {'确定'});
        end

        % Button pushed function: selectDist
        function selectDistButtonPushed(app, event)
            mainDir = uigetdir('D:\农房工程文件夹','选择文件夹'); % 资料整理_>村级编码
            app.subDir = dir(mainDir); %确定子文件夹
            app.subDir(1) = [];app.subDir(1) = []; %删除隐藏文件夹
            app.n = length(app.subDir); %户数
            app.dirPath.Text = mainDir;
            app.addDist(app.subDir);
            
%             app.nameRule.SelectedObject.Text = '';
%             app.rotateRule.SelectedObject.Text = '';
%             app.Button1.Text = '02_身份证';
%             app.rotateLeft.Text = '左转90';
        end

        % Callback function: UIFigure, save_next
        function save_nextButtonPushed(app, event)
            app.TextArea.Value = '';
            % 清空图像
            for b = 1:20
                app.GridLayout1.Children(b).ImageSource = '';
                app.GridLayout1.Children(b).Visible = 'off';
            end
            % 打开下一文件夹
            app.No = app.No + 1;
            if app.No>app.n
               uiconfirm(app.UIFigure,'当前村最后一户','提示','Options',{'确定'});
               return;
            end
            app.addDist(app.subDir);
            app.reTiNo = struct('N1_',1,'N2_',1,'N3_',1,'N4_',1);
        end

        % Image clicked function: Image1, Image10, Image11, 
        % Image12, Image13, Image14, Image15, Image16, Image17, 
        % Image18, Image19, Image2, Image20, Image3, Image4, 
        % Image5, Image6, Image7, Image8, Image9
        function Image1Clicked(app, event)
            % 点击图片，记录修改信息
            imgObj = event.Source;
            app.reName(imgObj);
            app.reRotate(imgObj);
        end

        % Button pushed function: goto_n
        function goto_nButtonPushed(app, event)
            % 清空图像
            for b = 1:20
                app.GridLayout1.Children(b).ImageSource = '';
                app.GridLayout1.Children(b).Visible = 'off';
            end
            
            % 打开第n个文件夹
            app.No = str2num(app.TextArea.Value{1});
            if app.No>app.n
               uiconfirm(app.UIFigure,'当前村最后一户','提示','Options',{'确定'});
               return;
            end
            app.addDist(app.subDir);
            app.reTiNo = struct('N1_',1,'N2_',1,'N3_',1,'N4_',1);
        end

        % Button pushed function: save_last
        function save_lastButtonPushed(app, event)
            app.TextArea.Value = '';
            % 清空图像
            for b = 1:20
                app.GridLayout1.Children(b).ImageSource = '';
                app.GridLayout1.Children(b).Visible = 'off';
            end
            % 打开下一文件夹
            app.No = app.No - 1;
            if app.No<1
               uiconfirm(app.UIFigure,'当前村第一户','提示','Options',{'确定'});
               return;
            end
            app.addDist(app.subDir);
            app.reTiNo = struct('N1_',1,'N2_',1,'N3_',1,'N4_',1);
        end

        % Selection changed function: rotateRule
        function updateRotate(app, event)
            selectedButton = app.rotateRule.SelectedObject;
            % 更新旋转规则
            if selectedButton.Text == '左转90'
                app.angle = 90;
            else
                app.angle = 270;
            end
            app.reTitle = '';
%             uiconfirm(app.UIFigure, ['命名规则：' app.reTitle newline '旋转规则：' num2str(app.angle) '°'], '提示', 'Options', {'确定'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @save_nextButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.nameRule = uibuttongroup(app.LeftPanel);
            app.nameRule.SelectionChangedFcn = createCallbackFcn(app, @updateName, true);
            app.nameRule.Title = '命名规则';
            app.Button1 = uitogglebutton(app.nameRule);
            app.Button1.Text = '身份证明_1';
            app.Button1.Value = true;
            app.Button2 = uitogglebutton(app.nameRule);
            app.Button2.Text = '户口本_2';
            app.Button3 = uitogglebutton(app.nameRule);
            app.Button3.Text = '权源资料_3';
            app.Button4 = uitogglebutton(app.nameRule);
            app.Button4.Text = '房屋照片_4';
            app.selectDist = uibutton(app.LeftPanel, 'push');
            app.selectDist.ButtonPushedFcn = createCallbackFcn(app, @selectDistButtonPushed, true);
            app.selectDist.Text = '选择文件夹';
            app.dirPath = uilabel(app.LeftPanel);
            app.dirPath.Text = '';
            app.goto_n = uibutton(app.LeftPanel, 'push');
            app.goto_n.ButtonPushedFcn = createCallbackFcn(app, @goto_nButtonPushed, true);
            app.goto_n.Text = '跳转';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '输入文件夹序号：';
            app.TextArea = uitextarea(app.LeftPanel);
            app.dirName = uilabel(app.LeftPanel);
            app.dirName.Text = '';
            app.dirInfo = uilabel(app.LeftPanel);
            app.dirInfo.Text = '';
            app.save_last = uibutton(app.LeftPanel, 'push');
            app.save_last.ButtonPushedFcn = createCallbackFcn(app, @save_lastButtonPushed, true);
            app.save_last.Text = '上一个文件夹';
            app.save_next = uibutton(app.LeftPanel, 'push');
            app.save_next.ButtonPushedFcn = createCallbackFcn(app, @save_nextButtonPushed, true);
            app.save_next.Text = '下一个文件夹';
            app.rotateRule = uibuttongroup(app.LeftPanel);
            app.rotateRule.SelectionChangedFcn = createCallbackFcn(app, @updateRotate, true);
            app.rotateRule.Title = '旋转';
            app.rotateLeft = uitogglebutton(app.rotateRule);
            app.rotateLeft.Text = '左转90';
            app.rotateLeft.Value = true;
            app.rotateRight = uitogglebutton(app.rotateRule);
            app.rotateRight.Text = '右转90';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.RightPanel);
            app.Image1 = uiimage(app.GridLayout1);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image1.Visible = 'off';
            app.Image2 = uiimage(app.GridLayout1);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image2.Visible = 'off';
            app.Image3 = uiimage(app.GridLayout1);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image3.Visible = 'off';
            app.Image4 = uiimage(app.GridLayout1);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image4.Visible = 'off';
            app.Image5 = uiimage(app.GridLayout1);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image5.Visible = 'off';
            app.Image6 = uiimage(app.GridLayout1);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image6.Visible = 'off';
            app.Image7 = uiimage(app.GridLayout1);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image7.Visible = 'off';
            app.Image8 = uiimage(app.GridLayout1);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image8.Visible = 'off';
            app.Image9 = uiimage(app.GridLayout1);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image9.Visible = 'off';
            app.Image10 = uiimage(app.GridLayout1);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image10.Visible = 'off';
            app.Image11 = uiimage(app.GridLayout1);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image11.Visible = 'off';
            app.Image12 = uiimage(app.GridLayout1);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image12.Visible = 'off';
            app.Image13 = uiimage(app.GridLayout1);
            app.Image13.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image13.Visible = 'off';
            app.Image14 = uiimage(app.GridLayout1);
            app.Image14.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image14.Visible = 'off';
            app.Image15 = uiimage(app.GridLayout1);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image15.Visible = 'off';
            app.Image16 = uiimage(app.GridLayout1);
            app.Image16.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image16.Visible = 'off';
            app.Image17 = uiimage(app.GridLayout1);
            app.Image17.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image17.Visible = 'off';
            app.Image18 = uiimage(app.GridLayout1);
            app.Image18.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image18.Visible = 'off';
            app.Image19 = uiimage(app.GridLayout1);
            app.Image19.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image19.Visible = 'off';
            app.Image20 = uiimage(app.GridLayout1);
            app.Image20.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image20.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A5_rename_Hengshan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end