classdef A5_rename_Qidong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        selectDist   matlab.ui.control.Button
        dirPath      matlab.ui.control.Label
        nameRule     matlab.ui.container.Panel
        Button02     matlab.ui.control.Button
        Button03     matlab.ui.control.Button
        Button08     matlab.ui.control.Button
        Button11     matlab.ui.control.Button
        Button13     matlab.ui.control.Button
        Button99     matlab.ui.control.Button
        rotateRule   matlab.ui.container.Panel
        rotateLeft   matlab.ui.control.Button
        rotateRight  matlab.ui.control.Button
        Goto00000    matlab.ui.container.Panel
        input        matlab.ui.control.TextArea
        goto_n       matlab.ui.control.Button
        folderInfo   matlab.ui.container.Panel
        dirName      matlab.ui.control.Label
        dirInfo      matlab.ui.control.Label
        save_last    matlab.ui.control.Button
        save_next    matlab.ui.control.Button
        descendDist  matlab.ui.control.Button
        RightPanel   matlab.ui.container.Panel
        imgGrid      matlab.ui.container.GridLayout
        Image1       matlab.ui.control.Image
        Image2       matlab.ui.control.Image
        Image3       matlab.ui.control.Image
        Image4       matlab.ui.control.Image
        Image5       matlab.ui.control.Image
        Image6       matlab.ui.control.Image
        Image7       matlab.ui.control.Image
        Image8       matlab.ui.control.Image
        Image9       matlab.ui.control.Image
        Image10      matlab.ui.control.Image
        Image11      matlab.ui.control.Image
        Image12      matlab.ui.control.Image
        Image13      matlab.ui.control.Image
        Image14      matlab.ui.control.Image
        Image15      matlab.ui.control.Image
        Image16      matlab.ui.control.Image
        Image17      matlab.ui.control.Image
        Image18      matlab.ui.control.Image
        Image19      matlab.ui.control.Image
        Image20      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        reTitle = '';   % Surface title to retitle
        reTiNo = struct('N02_',1,'N03_',1,'N08_',1,'N11_',1,'N13_',1,'N99_',1); % 记录重命名次数
        subDir = '';
        No = 1;
        JC = '';
        n = 0;
        angle = 0;
    end
    
    methods (Access = public)
        
        function addDist(app,subDir)    
            subDirpath = fullfile(subDir(app.No).folder, subDir(app.No).name, '\');
            app.reTiNo = struct('N02_',1,'N03_',1,'N08_',1,'N11_',1,'N13_',1,'N99_',1);
            
            app.input.Value = '';
            app.dirInfo.Text = ['共' num2str(app.n) '个文件夹，当前第' num2str(app.No) '个']; % 文件信息
            app.dirName.Text = [subDir(app.No).name];% 文件夹名
            
            imgDir = dir([subDirpath '*.jpg']);
            
%            app.imgGrid = uigridlayout(app.RightPanel);
            app.imgGrid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};
            app.imgGrid.RowHeight = {'1x', '1x', '1x'}; % 重置网格
            
            app.addImage(imgDir);
        end
        
        function addImage(app, imgDir)
            % 清空图像
            for b = 1:20
                app.imgGrid.Children(b).ImageSource = '';
                app.imgGrid.Children(b).Visible = 'off';
            end 
            % 动态加载图片
            figureWait = waitbar(0,'加载图像中...');
            for j = 1:length(imgDir) % 遍历结构体就可以一一处理图片了
                waitbar(j/length(imgDir), figureWait, '加载图像中...');
                
                % 压缩图片
                ImgPath = fullfile(imgDir(j).folder,imgDir(j).name);
                OrigImg = imread(ImgPath);
                NewImg = imresize(OrigImg,[240,240]);
                
                if numel(size(NewImg))<3
                    NewImg= gray2rgb(NewImg);
                end
                
                % 更新图片属性
                app.imgGrid.Children(j).Visible = 'on';
                app.imgGrid.Children(j).ImageSource = NewImg;
%                 app.imgGrid.Children(j).ImageSource = ImgPath;
                app.imgGrid.Children(j).Tag = imgDir(j).name;
                app.imgGrid.Children(j).Tooltip = imgDir(j).name;
            end
            close(figureWait);
        end
        
        function reName(app, imgObj)
            oldname = fullfile(app.dirPath.Text,app.dirName.Text,imgObj.Tag);
        
            % 截取reTitle，作为字段
            strField = extractBefore(app.reTitle,3);
            newname = [app.reTitle '_' num2str(app.reTiNo.(['N' strField '_'])) '.jpg'];
            
            % 系统函数 重命名rename
            system(['RENAME ' 32 ,'"',oldname,'"',32 newname]);
%             restr = ['!RENAME', ' "', oldname, '" ', newname];
%             eval(restr);
            if ans == 0
                imgObj.Tooltip = [app.reTitle '_' num2str(app.reTiNo.(['N' strField '_']))];
                imgObj.Tag = [imgObj.Tooltip '.jpg'];
                app.reTiNo.(['N' strField '_']) = app.reTiNo.(['N' strField '_']) + 1;
            end
        end
        
        function reRotate(app, imgObj)
            OrigImg = imread(fullfile(app.dirPath.Text,app.dirName.Text,imgObj.Tag));
            OrigImg = imrotate(OrigImg, app.angle);
            
            % 缩略图旋转
            smallImg = imresize(OrigImg,[240,240]);
            
            if numel(size(smallImg))<3
                smallImg= gray2rgb(smallImg);
            end
            
            % 更新图片属性
            imgObj.ImageSource = smallImg;
            imwrite(OrigImg, fullfile(app.dirPath.Text,app.dirName.Text,imgObj.Tag));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             system('systeminfo > E:\1.txt');
%             localData = readtable('E:\1.txt','Delimiter','\t','Delimiter','space','MultipleDelimsAsOne',1,'ReadVariableNames',0);
%             localData = table2array(localData);
%             for i=1:length(localData)
%                 if contains(localData{i,1},"主机名")
%                     myNAME = localData{i,2};
%                 end
%                 if contains(localData{i,1},"IP")
%                     myIP = localData{i+1,2};
%                     myMAC = localData{i+2,2};
%                 end
%             end
%             newstr = strrep(myNAME,"-","")+strrep(myIP,".","")+strrep(myMAC,":","");
%             str1 =lower(newstr);
%             lsj = ["aa"	"0000"
%                     "ab"	"0001"
%                     "ac"	"0002"
%                     "ad"	"0003"
%                     "ae"	"0004"
%                     "af"	"0005"
%                     "ag"	"0006"
%                     "ah"	"0007"
%                     "ai"	"0008"
%                     "aj"	"0009"
%                     "ak"	"0010"
%                     "al"	"0011"
%                     "am"	"0012"
%                     "an"	"0013"
%                     "ao"	"0014"
%                     "ap"	"0015"
%                     "aq"	"0016"
%                     "ar"	"0017"
%                     "as"	"0018"
%                     "at"	"0019"
%                     "au"	"0020"
%                     "av"	"0021"
%                     "aw"	"0022"
%                     "ax"	"0023"
%                     "ay"	"0024"
%                     "az"	"0025"
%                     "a0"	"0026"
%                     "a1"	"0027"
%                     "a2"	"0028"
%                     "a3"	"0029"
%                     "a4"	"0030"
%                     "a5"	"0031"
%                     "a6"	"0032"
%                     "a7"	"0033"
%                     "a8"	"0034"
%                     "a9"	"0035"
%                     "ba"	"0036"
%                     "bb"	"0037"
%                     "bc"	"0038"
%                     "bd"	"0039"
%                     "be"	"0040"
%                     "bf"	"0041"
%                     "bg"	"0042"
%                     "bh"	"0043"
%                     "bi"	"0044"
%                     "bj"	"0045"
%                     "bk"	"0046"
%                     "bl"	"0047"
%                     "bm"	"0048"
%                     "bn"	"0049"
%                     "bo"	"0050"
%                     "bp"	"0051"
%                     "bq"	"0052"
%                     "br"	"0053"
%                     "bs"	"0054"
%                     "bt"	"0055"
%                     "bu"	"0056"
%                     "bv"	"0057"
%                     "bw"	"0058"
%                     "bx"	"0059"
%                     "by"	"0060"
%                     "bz"	"0061"
%                     "b0"	"0062"
%                     "b1"	"0063"
%                     "b2"	"0064"
%                     "b3"	"0065"
%                     "b4"	"0066"
%                     "b5"	"0067"
%                     "b6"	"0068"
%                     "b7"	"0069"
%                     "b8"	"0070"
%                     "b9"	"0071"
%                     "ca"	"0072"
%                     "cb"	"0073"
%                     "cc"	"0074"
%                     "cd"	"0075"
%                     "ce"	"0076"
%                     "cf"	"0077"
%                     "cg"	"0078"
%                     "ch"	"0079"
%                     "ci"	"0080"
%                     "cj"	"0081"
%                     "ck"	"0082"
%                     "cl"	"0083"
%                     "cm"	"0084"
%                     "cn"	"0085"
%                     "co"	"0086"
%                     "cp"	"0087"
%                     "cq"	"0088"
%                     "cr"	"0089"
%                     "cs"	"0090"
%                     "ct"	"0091"
%                     "cu"	"0092"
%                     "cv"	"0093"
%                     "cw"	"0094"
%                     "cx"	"0095"
%                     "cy"	"0096"
%                     "cz"	"0097"
%                     "c0"	"0098"
%                     "c1"	"0099"
%                     "c2"	"0100"
%                     "c3"	"0101"
%                     "c4"	"0102"
%                     "c5"	"0103"
%                     "c6"	"0104"
%                     "c7"	"0105"
%                     "c8"	"0106"
%                     "c9"	"0107"
%                     "da"	"0108"
%                     "db"	"0109"
%                     "dc"	"0110"
%                     "dd"	"0111"
%                     "de"	"0112"
%                     "df"	"0113"
%                     "dg"	"0114"
%                     "dh"	"0115"
%                     "di"	"0116"
%                     "dj"	"0117"
%                     "dk"	"0118"
%                     "dl"	"0119"
%                     "dm"	"0120"
%                     "dn"	"0121"
%                     "do"	"0122"
%                     "dp"	"0123"
%                     "dq"	"0124"
%                     "dr"	"0125"
%                     "ds"	"0126"
%                     "dt"	"0127"
%                     "du"	"0128"
%                     "dv"	"0129"
%                     "dw"	"0130"
%                     "dx"	"0131"
%                     "dy"	"0132"
%                     "dz"	"0133"
%                     "d0"	"0134"
%                     "d1"	"0135"
%                     "d2"	"0136"
%                     "d3"	"0137"
%                     "d4"	"0138"
%                     "d5"	"0139"
%                     "d6"	"0140"
%                     "d7"	"0141"
%                     "d8"	"0142"
%                     "d9"	"0143"
%                     "ea"	"0144"
%                     "eb"	"0145"
%                     "ec"	"0146"
%                     "ed"	"0147"
%                     "ee"	"0148"
%                     "ef"	"0149"
%                     "eg"	"0150"
%                     "eh"	"0151"
%                     "ei"	"0152"
%                     "ej"	"0153"
%                     "ek"	"0154"
%                     "el"	"0155"
%                     "em"	"0156"
%                     "en"	"0157"
%                     "eo"	"0158"
%                     "ep"	"0159"
%                     "eq"	"0160"
%                     "er"	"0161"
%                     "es"	"0162"
%                     "et"	"0163"
%                     "eu"	"0164"
%                     "ev"	"0165"
%                     "ew"	"0166"
%                     "ex"	"0167"
%                     "ey"	"0168"
%                     "ez"	"0169"
%                     "e0"	"0170"
%                     "e1"	"0171"
%                     "e2"	"0172"
%                     "e3"	"0173"
%                     "e4"	"0174"
%                     "e5"	"0175"
%                     "e6"	"0176"
%                     "e7"	"0177"
%                     "e8"	"0178"
%                     "e9"	"0179"
%                     "fa"	"0180"
%                     "fb"	"0181"
%                     "fc"	"0182"
%                     "fd"	"0183"
%                     "fe"	"0184"
%                     "ff"	"0185"
%                     "fg"	"0186"
%                     "fh"	"0187"
%                     "fi"	"0188"
%                     "fj"	"0189"
%                     "fk"	"0190"
%                     "fl"	"0191"
%                     "fm"	"0192"
%                     "fn"	"0193"
%                     "fo"	"0194"
%                     "fp"	"0195"
%                     "fq"	"0196"
%                     "fr"	"0197"
%                     "fs"	"0198"
%                     "ft"	"0199"
%                     "fu"	"0200"
%                     "fv"	"0201"
%                     "fw"	"0202"
%                     "fx"	"0203"
%                     "fy"	"0204"
%                     "fz"	"0205"
%                     "f0"	"0206"
%                     "f1"	"0207"
%                     "f2"	"0208"
%                     "f3"	"0209"
%                     "f4"	"0210"
%                     "f5"	"0211"
%                     "f6"	"0212"
%                     "f7"	"0213"
%                     "f8"	"0214"
%                     "f9"	"0215"
%                     "ga"	"0216"
%                     "gb"	"0217"
%                     "gc"	"0218"
%                     "gd"	"0219"
%                     "ge"	"0220"
%                     "gf"	"0221"
%                     "gg"	"0222"
%                     "gh"	"0223"
%                     "gi"	"0224"
%                     "gj"	"0225"
%                     "gk"	"0226"
%                     "gl"	"0227"
%                     "gm"	"0228"
%                     "gn"	"0229"
%                     "go"	"0230"
%                     "gp"	"0231"
%                     "gq"	"0232"
%                     "gr"	"0233"
%                     "gs"	"0234"
%                     "gt"	"0235"
%                     "gu"	"0236"
%                     "gv"	"0237"
%                     "gw"	"0238"
%                     "gx"	"0239"
%                     "gy"	"0240"
%                     "gz"	"0241"
%                     "g0"	"0242"
%                     "g1"	"0243"
%                     "g2"	"0244"
%                     "g3"	"0245"
%                     "g4"	"0246"
%                     "g5"	"0247"
%                     "g6"	"0248"
%                     "g7"	"0249"
%                     "g8"	"0250"
%                     "g9"	"0251"
%                     "ha"	"0252"
%                     "hb"	"0253"
%                     "hc"	"0254"
%                     "hd"	"0255"
%                     "he"	"0256"
%                     "hf"	"0257"
%                     "hg"	"0258"
%                     "hh"	"0259"
%                     "hi"	"0260"
%                     "hj"	"0261"
%                     "hk"	"0262"
%                     "hl"	"0263"
%                     "hm"	"0264"
%                     "hn"	"0265"
%                     "ho"	"0266"
%                     "hp"	"0267"
%                     "hq"	"0268"
%                     "hr"	"0269"
%                     "hs"	"0270"
%                     "ht"	"0271"
%                     "hu"	"0272"
%                     "hv"	"0273"
%                     "hw"	"0274"
%                     "hx"	"0275"
%                     "hy"	"0276"
%                     "hz"	"0277"
%                     "h0"	"0278"
%                     "h1"	"0279"
%                     "h2"	"0280"
%                     "h3"	"0281"
%                     "h4"	"0282"
%                     "h5"	"0283"
%                     "h6"	"0284"
%                     "h7"	"0285"
%                     "h8"	"0286"
%                     "h9"	"0287"
%                     "ia"	"0288"
%                     "ib"	"0289"
%                     "ic"	"0290"
%                     "id"	"0291"
%                     "ie"	"0292"
%                     "if"	"0293"
%                     "ig"	"0294"
%                     "ih"	"0295"
%                     "ii"	"0296"
%                     "ij"	"0297"
%                     "ik"	"0298"
%                     "il"	"0299"
%                     "im"	"0300"
%                     "in"	"0301"
%                     "io"	"0302"
%                     "ip"	"0303"
%                     "iq"	"0304"
%                     "ir"	"0305"
%                     "is"	"0306"
%                     "it"	"0307"
%                     "iu"	"0308"
%                     "iv"	"0309"
%                     "iw"	"0310"
%                     "ix"	"0311"
%                     "iy"	"0312"
%                     "iz"	"0313"
%                     "i0"	"0314"
%                     "i1"	"0315"
%                     "i2"	"0316"
%                     "i3"	"0317"
%                     "i4"	"0318"
%                     "i5"	"0319"
%                     "i6"	"0320"
%                     "i7"	"0321"
%                     "i8"	"0322"
%                     "i9"	"0323"
%                     "ja"	"0324"
%                     "jb"	"0325"
%                     "jc"	"0326"
%                     "jd"	"0327"
%                     "je"	"0328"
%                     "jf"	"0329"
%                     "jg"	"0330"
%                     "jh"	"0331"
%                     "ji"	"0332"
%                     "jj"	"0333"
%                     "jk"	"0334"
%                     "jl"	"0335"
%                     "jm"	"0336"
%                     "jn"	"0337"
%                     "jo"	"0338"
%                     "jp"	"0339"
%                     "jq"	"0340"
%                     "jr"	"0341"
%                     "js"	"0342"
%                     "jt"	"0343"
%                     "ju"	"0344"
%                     "jv"	"0345"
%                     "jw"	"0346"
%                     "jx"	"0347"
%                     "jy"	"0348"
%                     "jz"	"0349"
%                     "j0"	"0350"
%                     "j1"	"0351"
%                     "j2"	"0352"
%                     "j3"	"0353"
%                     "j4"	"0354"
%                     "j5"	"0355"
%                     "j6"	"0356"
%                     "j7"	"0357"
%                     "j8"	"0358"
%                     "j9"	"0359"
%                     "ka"	"0360"
%                     "kb"	"0361"
%                     "kc"	"0362"
%                     "kd"	"0363"
%                     "ke"	"0364"
%                     "kf"	"0365"
%                     "kg"	"0366"
%                     "kh"	"0367"
%                     "ki"	"0368"
%                     "kj"	"0369"
%                     "kk"	"0370"
%                     "kl"	"0371"
%                     "km"	"0372"
%                     "kn"	"0373"
%                     "ko"	"0374"
%                     "kp"	"0375"
%                     "kq"	"0376"
%                     "kr"	"0377"
%                     "ks"	"0378"
%                     "kt"	"0379"
%                     "ku"	"0380"
%                     "kv"	"0381"
%                     "kw"	"0382"
%                     "kx"	"0383"
%                     "ky"	"0384"
%                     "kz"	"0385"
%                     "k0"	"0386"
%                     "k1"	"0387"
%                     "k2"	"0388"
%                     "k3"	"0389"
%                     "k4"	"0390"
%                     "k5"	"0391"
%                     "k6"	"0392"
%                     "k7"	"0393"
%                     "k8"	"0394"
%                     "k9"	"0395"
%                     "la"	"0396"
%                     "lb"	"0397"
%                     "lc"	"0398"
%                     "ld"	"0399"
%                     "le"	"0400"
%                     "lf"	"0401"
%                     "lg"	"0402"
%                     "lh"	"0403"
%                     "li"	"0404"
%                     "lj"	"0405"
%                     "lk"	"0406"
%                     "ll"	"0407"
%                     "lm"	"0408"
%                     "ln"	"0409"
%                     "lo"	"0410"
%                     "lp"	"0411"
%                     "lq"	"0412"
%                     "lr"	"0413"
%                     "ls"	"0414"
%                     "lt"	"0415"
%                     "lu"	"0416"
%                     "lv"	"0417"
%                     "lw"	"0418"
%                     "lx"	"0419"
%                     "ly"	"0420"
%                     "lz"	"0421"
%                     "l0"	"0422"
%                     "l1"	"0423"
%                     "l2"	"0424"
%                     "l3"	"0425"
%                     "l4"	"0426"
%                     "l5"	"0427"
%                     "l6"	"0428"
%                     "l7"	"0429"
%                     "l8"	"0430"
%                     "l9"	"0431"
%                     "ma"	"0432"
%                     "mb"	"0433"
%                     "mc"	"0434"
%                     "md"	"0435"
%                     "me"	"0436"
%                     "mf"	"0437"
%                     "mg"	"0438"
%                     "mh"	"0439"
%                     "mi"	"0440"
%                     "mj"	"0441"
%                     "mk"	"0442"
%                     "ml"	"0443"
%                     "mm"	"0444"
%                     "mn"	"0445"
%                     "mo"	"0446"
%                     "mp"	"0447"
%                     "mq"	"0448"
%                     "mr"	"0449"
%                     "ms"	"0450"
%                     "mt"	"0451"
%                     "mu"	"0452"
%                     "mv"	"0453"
%                     "mw"	"0454"
%                     "mx"	"0455"
%                     "my"	"0456"
%                     "mz"	"0457"
%                     "m0"	"0458"
%                     "m1"	"0459"
%                     "m2"	"0460"
%                     "m3"	"0461"
%                     "m4"	"0462"
%                     "m5"	"0463"
%                     "m6"	"0464"
%                     "m7"	"0465"
%                     "m8"	"0466"
%                     "m9"	"0467"
%                     "na"	"0468"
%                     "nb"	"0469"
%                     "nc"	"0470"
%                     "nd"	"0471"
%                     "ne"	"0472"
%                     "nf"	"0473"
%                     "ng"	"0474"
%                     "nh"	"0475"
%                     "ni"	"0476"
%                     "nj"	"0477"
%                     "nk"	"0478"
%                     "nl"	"0479"
%                     "nm"	"0480"
%                     "nn"	"0481"
%                     "no"	"0482"
%                     "np"	"0483"
%                     "nq"	"0484"
%                     "nr"	"0485"
%                     "ns"	"0486"
%                     "nt"	"0487"
%                     "nu"	"0488"
%                     "nv"	"0489"
%                     "nw"	"0490"
%                     "nx"	"0491"
%                     "ny"	"0492"
%                     "nz"	"0493"
%                     "n0"	"0494"
%                     "n1"	"0495"
%                     "n2"	"0496"
%                     "n3"	"0497"
%                     "n4"	"0498"
%                     "n5"	"0499"
%                     "n6"	"0500"
%                     "n7"	"0501"
%                     "n8"	"0502"
%                     "n9"	"0503"
%                     "oa"	"0504"
%                     "ob"	"0505"
%                     "oc"	"0506"
%                     "od"	"0507"
%                     "oe"	"0508"
%                     "of"	"0509"
%                     "og"	"0510"
%                     "oh"	"0511"
%                     "oi"	"0512"
%                     "oj"	"0513"
%                     "ok"	"0514"
%                     "ol"	"0515"
%                     "om"	"0516"
%                     "on"	"0517"
%                     "oo"	"0518"
%                     "op"	"0519"
%                     "oq"	"0520"
%                     "or"	"0521"
%                     "os"	"0522"
%                     "ot"	"0523"
%                     "ou"	"0524"
%                     "ov"	"0525"
%                     "ow"	"0526"
%                     "ox"	"0527"
%                     "oy"	"0528"
%                     "oz"	"0529"
%                     "o0"	"0530"
%                     "o1"	"0531"
%                     "o2"	"0532"
%                     "o3"	"0533"
%                     "o4"	"0534"
%                     "o5"	"0535"
%                     "o6"	"0536"
%                     "o7"	"0537"
%                     "o8"	"0538"
%                     "o9"	"0539"
%                     "pa"	"0540"
%                     "pb"	"0541"
%                     "pc"	"0542"
%                     "pd"	"0543"
%                     "pe"	"0544"
%                     "pf"	"0545"
%                     "pg"	"0546"
%                     "ph"	"0547"
%                     "pi"	"0548"
%                     "pj"	"0549"
%                     "pk"	"0550"
%                     "pl"	"0551"
%                     "pm"	"0552"
%                     "pn"	"0553"
%                     "po"	"0554"
%                     "pp"	"0555"
%                     "pq"	"0556"
%                     "pr"	"0557"
%                     "ps"	"0558"
%                     "pt"	"0559"
%                     "pu"	"0560"
%                     "pv"	"0561"
%                     "pw"	"0562"
%                     "px"	"0563"
%                     "py"	"0564"
%                     "pz"	"0565"
%                     "p0"	"0566"
%                     "p1"	"0567"
%                     "p2"	"0568"
%                     "p3"	"0569"
%                     "p4"	"0570"
%                     "p5"	"0571"
%                     "p6"	"0572"
%                     "p7"	"0573"
%                     "p8"	"0574"
%                     "p9"	"0575"
%                     "qa"	"0576"
%                     "qb"	"0577"
%                     "qc"	"0578"
%                     "qd"	"0579"
%                     "qe"	"0580"
%                     "qf"	"0581"
%                     "qg"	"0582"
%                     "qh"	"0583"
%                     "qi"	"0584"
%                     "qj"	"0585"
%                     "qk"	"0586"
%                     "ql"	"0587"
%                     "qm"	"0588"
%                     "qn"	"0589"
%                     "qo"	"0590"
%                     "qp"	"0591"
%                     "qq"	"0592"
%                     "qr"	"0593"
%                     "qs"	"0594"
%                     "qt"	"0595"
%                     "qu"	"0596"
%                     "qv"	"0597"
%                     "qw"	"0598"
%                     "qx"	"0599"
%                     "qy"	"0600"
%                     "qz"	"0601"
%                     "q0"	"0602"
%                     "q1"	"0603"
%                     "q2"	"0604"
%                     "q3"	"0605"
%                     "q4"	"0606"
%                     "q5"	"0607"
%                     "q6"	"0608"
%                     "q7"	"0609"
%                     "q8"	"0610"
%                     "q9"	"0611"
%                     "ra"	"0612"
%                     "rb"	"0613"
%                     "rc"	"0614"
%                     "rd"	"0615"
%                     "re"	"0616"
%                     "rf"	"0617"
%                     "rg"	"0618"
%                     "rh"	"0619"
%                     "ri"	"0620"
%                     "rj"	"0621"
%                     "rk"	"0622"
%                     "rl"	"0623"
%                     "rm"	"0624"
%                     "rn"	"0625"
%                     "ro"	"0626"
%                     "rp"	"0627"
%                     "rq"	"0628"
%                     "rr"	"0629"
%                     "rs"	"0630"
%                     "rt"	"0631"
%                     "ru"	"0632"
%                     "rv"	"0633"
%                     "rw"	"0634"
%                     "rx"	"0635"
%                     "ry"	"0636"
%                     "rz"	"0637"
%                     "r0"	"0638"
%                     "r1"	"0639"
%                     "r2"	"0640"
%                     "r3"	"0641"
%                     "r4"	"0642"
%                     "r5"	"0643"
%                     "r6"	"0644"
%                     "r7"	"0645"
%                     "r8"	"0646"
%                     "r9"	"0647"
%                     "sa"	"0648"
%                     "sb"	"0649"
%                     "sc"	"0650"
%                     "sd"	"0651"
%                     "se"	"0652"
%                     "sf"	"0653"
%                     "sg"	"0654"
%                     "sh"	"0655"
%                     "si"	"0656"
%                     "sj"	"0657"
%                     "sk"	"0658"
%                     "sl"	"0659"
%                     "sm"	"0660"
%                     "sn"	"0661"
%                     "so"	"0662"
%                     "sp"	"0663"
%                     "sq"	"0664"
%                     "sr"	"0665"
%                     "ss"	"0666"
%                     "st"	"0667"
%                     "su"	"0668"
%                     "sv"	"0669"
%                     "sw"	"0670"
%                     "sx"	"0671"
%                     "sy"	"0672"
%                     "sz"	"0673"
%                     "s0"	"0674"
%                     "s1"	"0675"
%                     "s2"	"0676"
%                     "s3"	"0677"
%                     "s4"	"0678"
%                     "s5"	"0679"
%                     "s6"	"0680"
%                     "s7"	"0681"
%                     "s8"	"0682"
%                     "s9"	"0683"
%                     "ta"	"0684"
%                     "tb"	"0685"
%                     "tc"	"0686"
%                     "td"	"0687"
%                     "te"	"0688"
%                     "tf"	"0689"
%                     "tg"	"0690"
%                     "th"	"0691"
%                     "ti"	"0692"
%                     "tj"	"0693"
%                     "tk"	"0694"
%                     "tl"	"0695"
%                     "tm"	"0696"
%                     "tn"	"0697"
%                     "to"	"0698"
%                     "tp"	"0699"
%                     "tq"	"0700"
%                     "tr"	"0701"
%                     "ts"	"0702"
%                     "tt"	"0703"
%                     "tu"	"0704"
%                     "tv"	"0705"
%                     "tw"	"0706"
%                     "tx"	"0707"
%                     "ty"	"0708"
%                     "tz"	"0709"
%                     "t0"	"0710"
%                     "t1"	"0711"
%                     "t2"	"0712"
%                     "t3"	"0713"
%                     "t4"	"0714"
%                     "t5"	"0715"
%                     "t6"	"0716"
%                     "t7"	"0717"
%                     "t8"	"0718"
%                     "t9"	"0719"
%                     "ua"	"0720"
%                     "ub"	"0721"
%                     "uc"	"0722"
%                     "ud"	"0723"
%                     "ue"	"0724"
%                     "uf"	"0725"
%                     "ug"	"0726"
%                     "uh"	"0727"
%                     "ui"	"0728"
%                     "uj"	"0729"
%                     "uk"	"0730"
%                     "ul"	"0731"
%                     "um"	"0732"
%                     "un"	"0733"
%                     "uo"	"0734"
%                     "up"	"0735"
%                     "uq"	"0736"
%                     "ur"	"0737"
%                     "us"	"0738"
%                     "ut"	"0739"
%                     "uu"	"0740"
%                     "uv"	"0741"
%                     "uw"	"0742"
%                     "ux"	"0743"
%                     "uy"	"0744"
%                     "uz"	"0745"
%                     "u0"	"0746"
%                     "u1"	"0747"
%                     "u2"	"0748"
%                     "u3"	"0749"
%                     "u4"	"0750"
%                     "u5"	"0751"
%                     "u6"	"0752"
%                     "u7"	"0753"
%                     "u8"	"0754"
%                     "u9"	"0755"
%                     "va"	"0756"
%                     "vb"	"0757"
%                     "vc"	"0758"
%                     "vd"	"0759"
%                     "ve"	"0760"
%                     "vf"	"0761"
%                     "vg"	"0762"
%                     "vh"	"0763"
%                     "vi"	"0764"
%                     "vj"	"0765"
%                     "vk"	"0766"
%                     "vl"	"0767"
%                     "vm"	"0768"
%                     "vn"	"0769"
%                     "vo"	"0770"
%                     "vp"	"0771"
%                     "vq"	"0772"
%                     "vr"	"0773"
%                     "vs"	"0774"
%                     "vt"	"0775"
%                     "vu"	"0776"
%                     "vv"	"0777"
%                     "vw"	"0778"
%                     "vx"	"0779"
%                     "vy"	"0780"
%                     "vz"	"0781"
%                     "v0"	"0782"
%                     "v1"	"0783"
%                     "v2"	"0784"
%                     "v3"	"0785"
%                     "v4"	"0786"
%                     "v5"	"0787"
%                     "v6"	"0788"
%                     "v7"	"0789"
%                     "v8"	"0790"
%                     "v9"	"0791"
%                     "wa"	"0792"
%                     "wb"	"0793"
%                     "wc"	"0794"
%                     "wd"	"0795"
%                     "we"	"0796"
%                     "wf"	"0797"
%                     "wg"	"0798"
%                     "wh"	"0799"
%                     "wi"	"0800"
%                     "wj"	"0801"
%                     "wk"	"0802"
%                     "wl"	"0803"
%                     "wm"	"0804"
%                     "wn"	"0805"
%                     "wo"	"0806"
%                     "wp"	"0807"
%                     "wq"	"0808"
%                     "wr"	"0809"
%                     "ws"	"0810"
%                     "wt"	"0811"
%                     "wu"	"0812"
%                     "wv"	"0813"
%                     "ww"	"0814"
%                     "wx"	"0815"
%                     "wy"	"0816"
%                     "wz"	"0817"
%                     "w0"	"0818"
%                     "w1"	"0819"
%                     "w2"	"0820"
%                     "w3"	"0821"
%                     "w4"	"0822"
%                     "w5"	"0823"
%                     "w6"	"0824"
%                     "w7"	"0825"
%                     "w8"	"0826"
%                     "w9"	"0827"
%                     "xa"	"0828"
%                     "xb"	"0829"
%                     "xc"	"0830"
%                     "xd"	"0831"
%                     "xe"	"0832"
%                     "xf"	"0833"
%                     "xg"	"0834"
%                     "xh"	"0835"
%                     "xi"	"0836"
%                     "xj"	"0837"
%                     "xk"	"0838"
%                     "xl"	"0839"
%                     "xm"	"0840"
%                     "xn"	"0841"
%                     "xo"	"0842"
%                     "xp"	"0843"
%                     "xq"	"0844"
%                     "xr"	"0845"
%                     "xs"	"0846"
%                     "xt"	"0847"
%                     "xu"	"0848"
%                     "xv"	"0849"
%                     "xw"	"0850"
%                     "xx"	"0851"
%                     "xy"	"0852"
%                     "xz"	"0853"
%                     "x0"	"0854"
%                     "x1"	"0855"
%                     "x2"	"0856"
%                     "x3"	"0857"
%                     "x4"	"0858"
%                     "x5"	"0859"
%                     "x6"	"0860"
%                     "x7"	"0861"
%                     "x8"	"0862"
%                     "x9"	"0863"
%                     "ya"	"0864"
%                     "yb"	"0865"
%                     "yc"	"0866"
%                     "yd"	"0867"
%                     "ye"	"0868"
%                     "yf"	"0869"
%                     "yg"	"0870"
%                     "yh"	"0871"
%                     "yi"	"0872"
%                     "yj"	"0873"
%                     "yk"	"0874"
%                     "yl"	"0875"
%                     "ym"	"0876"
%                     "yn"	"0877"
%                     "yo"	"0878"
%                     "yp"	"0879"
%                     "yq"	"0880"
%                     "yr"	"0881"
%                     "ys"	"0882"
%                     "yt"	"0883"
%                     "yu"	"0884"
%                     "yv"	"0885"
%                     "yw"	"0886"
%                     "yx"	"0887"
%                     "yy"	"0888"
%                     "yz"	"0889"
%                     "y0"	"0890"
%                     "y1"	"0891"
%                     "y2"	"0892"
%                     "y3"	"0893"
%                     "y4"	"0894"
%                     "y5"	"0895"
%                     "y6"	"0896"
%                     "y7"	"0897"
%                     "y8"	"0898"
%                     "y9"	"0899"
%                     "za"	"0900"
%                     "zb"	"0901"
%                     "zc"	"0902"
%                     "zd"	"0903"
%                     "ze"	"0904"
%                     "zf"	"0905"
%                     "zg"	"0906"
%                     "zh"	"0907"
%                     "zi"	"0908"
%                     "zj"	"0909"
%                     "zk"	"0910"
%                     "zl"	"0911"
%                     "zm"	"0912"
%                     "zn"	"0913"
%                     "zo"	"0914"
%                     "zp"	"0915"
%                     "zq"	"0916"
%                     "zr"	"0917"
%                     "zs"	"0918"
%                     "zt"	"0919"
%                     "zu"	"0920"
%                     "zv"	"0921"
%                     "zw"	"0922"
%                     "zx"	"0923"
%                     "zy"	"0924"
%                     "zz"	"0925"
%                     "z0"	"0926"
%                     "z1"	"0927"
%                     "z2"	"0928"
%                     "z3"	"0929"
%                     "z4"	"0930"
%                     "z5"	"0931"
%                     "z6"	"0932"
%                     "z7"	"0933"
%                     "z8"	"0934"
%                     "z9"	"0935"
%                     "0a"	"0936"
%                     "0b"	"0937"
%                     "0c"	"0938"
%                     "0d"	"0939"
%                     "0e"	"0940"
%                     "0f"	"0941"
%                     "0g"	"0942"
%                     "0h"	"0943"
%                     "0i"	"0944"
%                     "0j"	"0945"
%                     "0k"	"0946"
%                     "0l"	"0947"
%                     "0m"	"0948"
%                     "0n"	"0949"
%                     "0o"	"0950"
%                     "0p"	"0951"
%                     "0q"	"0952"
%                     "0r"	"0953"
%                     "0s"	"0954"
%                     "0t"	"0955"
%                     "0u"	"0956"
%                     "0v"	"0957"
%                     "0w"	"0958"
%                     "0x"	"0959"
%                     "0y"	"0960"
%                     "0z"	"0961"
%                     "00"	"0962"
%                     "01"	"0963"
%                     "02"	"0964"
%                     "03"	"0965"
%                     "04"	"0966"
%                     "05"	"0967"
%                     "06"	"0968"
%                     "07"	"0969"
%                     "08"	"0970"
%                     "09"	"0971"
%                     "1a"	"0972"
%                     "1b"	"0973"
%                     "1c"	"0974"
%                     "1d"	"0975"
%                     "1e"	"0976"
%                     "1f"	"0977"
%                     "1g"	"0978"
%                     "1h"	"0979"
%                     "1i"	"0980"
%                     "1j"	"0981"
%                     "1k"	"0982"
%                     "1l"	"0983"
%                     "1m"	"0984"
%                     "1n"	"0985"
%                     "1o"	"0986"
%                     "1p"	"0987"
%                     "1q"	"0988"
%                     "1r"	"0989"
%                     "1s"	"0990"
%                     "1t"	"0991"
%                     "1u"	"0992"
%                     "1v"	"0993"
%                     "1w"	"0994"
%                     "1x"	"0995"
%                     "1y"	"0996"
%                     "1z"	"0997"
%                     "10"	"0998"
%                     "11"	"0999"
%                     "12"	"1000"
%                     "13"	"1001"
%                     "14"	"1002"
%                     "15"	"1003"
%                     "16"	"1004"
%                     "17"	"1005"
%                     "18"	"1006"
%                     "19"	"1007"
%                     "2a"	"1008"
%                     "2b"	"1009"
%                     "2c"	"1010"
%                     "2d"	"1011"
%                     "2e"	"1012"
%                     "2f"	"1013"
%                     "2g"	"1014"
%                     "2h"	"1015"
%                     "2i"	"1016"
%                     "2j"	"1017"
%                     "2k"	"1018"
%                     "2l"	"1019"
%                     "2m"	"1020"
%                     "2n"	"1021"
%                     "2o"	"1022"
%                     "2p"	"1023"
%                     "2q"	"1024"
%                     "2r"	"1025"
%                     "2s"	"1026"
%                     "2t"	"1027"
%                     "2u"	"1028"
%                     "2v"	"1029"
%                     "2w"	"1030"
%                     "2x"	"1031"
%                     "2y"	"1032"
%                     "2z"	"1033"
%                     "20"	"1034"
%                     "21"	"1035"
%                     "22"	"1036"
%                     "23"	"1037"
%                     "24"	"1038"
%                     "25"	"1039"
%                     "26"	"1040"
%                     "27"	"1041"
%                     "28"	"1042"
%                     "29"	"1043"
%                     "3a"	"1044"
%                     "3b"	"1045"
%                     "3c"	"1046"
%                     "3d"	"1047"
%                     "3e"	"1048"
%                     "3f"	"1049"
%                     "3g"	"1050"
%                     "3h"	"1051"
%                     "3i"	"1052"
%                     "3j"	"1053"
%                     "3k"	"1054"
%                     "3l"	"1055"
%                     "3m"	"1056"
%                     "3n"	"1057"
%                     "3o"	"1058"
%                     "3p"	"1059"
%                     "3q"	"1060"
%                     "3r"	"1061"
%                     "3s"	"1062"
%                     "3t"	"1063"
%                     "3u"	"1064"
%                     "3v"	"1065"
%                     "3w"	"1066"
%                     "3x"	"1067"
%                     "3y"	"1068"
%                     "3z"	"1069"
%                     "30"	"1070"
%                     "31"	"1071"
%                     "32"	"1072"
%                     "33"	"1073"
%                     "34"	"1074"
%                     "35"	"1075"
%                     "36"	"1076"
%                     "37"	"1077"
%                     "38"	"1078"
%                     "39"	"1079"
%                     "4a"	"1080"
%                     "4b"	"1081"
%                     "4c"	"1082"
%                     "4d"	"1083"
%                     "4e"	"1084"
%                     "4f"	"1085"
%                     "4g"	"1086"
%                     "4h"	"1087"
%                     "4i"	"1088"
%                     "4j"	"1089"
%                     "4k"	"1090"
%                     "4l"	"1091"
%                     "4m"	"1092"
%                     "4n"	"1093"
%                     "4o"	"1094"
%                     "4p"	"1095"
%                     "4q"	"1096"
%                     "4r"	"1097"
%                     "4s"	"1098"
%                     "4t"	"1099"
%                     "4u"	"1100"
%                     "4v"	"1101"
%                     "4w"	"1102"
%                     "4x"	"1103"
%                     "4y"	"1104"
%                     "4z"	"1105"
%                     "40"	"1106"
%                     "41"	"1107"
%                     "42"	"1108"
%                     "43"	"1109"
%                     "44"	"1110"
%                     "45"	"1111"
%                     "46"	"1112"
%                     "47"	"1113"
%                     "48"	"1114"
%                     "49"	"1115"
%                     "5a"	"1116"
%                     "5b"	"1117"
%                     "5c"	"1118"
%                     "5d"	"1119"
%                     "5e"	"1120"
%                     "5f"	"1121"
%                     "5g"	"1122"
%                     "5h"	"1123"
%                     "5i"	"1124"
%                     "5j"	"1125"
%                     "5k"	"1126"
%                     "5l"	"1127"
%                     "5m"	"1128"
%                     "5n"	"1129"
%                     "5o"	"1130"
%                     "5p"	"1131"
%                     "5q"	"1132"
%                     "5r"	"1133"
%                     "5s"	"1134"
%                     "5t"	"1135"
%                     "5u"	"1136"
%                     "5v"	"1137"
%                     "5w"	"1138"
%                     "5x"	"1139"
%                     "5y"	"1140"
%                     "5z"	"1141"
%                     "50"	"1142"
%                     "51"	"1143"
%                     "52"	"1144"
%                     "53"	"1145"
%                     "54"	"1146"
%                     "55"	"1147"
%                     "56"	"1148"
%                     "57"	"1149"
%                     "58"	"1150"
%                     "59"	"1151"
%                     "6a"	"1152"
%                     "6b"	"1153"
%                     "6c"	"1154"
%                     "6d"	"1155"
%                     "6e"	"1156"
%                     "6f"	"1157"
%                     "6g"	"1158"
%                     "6h"	"1159"
%                     "6i"	"1160"
%                     "6j"	"1161"
%                     "6k"	"1162"
%                     "6l"	"1163"
%                     "6m"	"1164"
%                     "6n"	"1165"
%                     "6o"	"1166"
%                     "6p"	"1167"
%                     "6q"	"1168"
%                     "6r"	"1169"
%                     "6s"	"1170"
%                     "6t"	"1171"
%                     "6u"	"1172"
%                     "6v"	"1173"
%                     "6w"	"1174"
%                     "6x"	"1175"
%                     "6y"	"1176"
%                     "6z"	"1177"
%                     "60"	"1178"
%                     "61"	"1179"
%                     "62"	"1180"
%                     "63"	"1181"
%                     "64"	"1182"
%                     "65"	"1183"
%                     "66"	"1184"
%                     "67"	"1185"
%                     "68"	"1186"
%                     "69"	"1187"
%                     "7a"	"1188"
%                     "7b"	"1189"
%                     "7c"	"1190"
%                     "7d"	"1191"
%                     "7e"	"1192"
%                     "7f"	"1193"
%                     "7g"	"1194"
%                     "7h"	"1195"
%                     "7i"	"1196"
%                     "7j"	"1197"
%                     "7k"	"1198"
%                     "7l"	"1199"
%                     "7m"	"1200"
%                     "7n"	"1201"
%                     "7o"	"1202"
%                     "7p"	"1203"
%                     "7q"	"1204"
%                     "7r"	"1205"
%                     "7s"	"1206"
%                     "7t"	"1207"
%                     "7u"	"1208"
%                     "7v"	"1209"
%                     "7w"	"1210"
%                     "7x"	"1211"
%                     "7y"	"1212"
%                     "7z"	"1213"
%                     "70"	"1214"
%                     "71"	"1215"
%                     "72"	"1216"
%                     "73"	"1217"
%                     "74"	"1218"
%                     "75"	"1219"
%                     "76"	"1220"
%                     "77"	"1221"
%                     "78"	"1222"
%                     "79"	"1223"
%                     "8a"	"1224"
%                     "8b"	"1225"
%                     "8c"	"1226"
%                     "8d"	"1227"
%                     "8e"	"1228"
%                     "8f"	"1229"
%                     "8g"	"1230"
%                     "8h"	"1231"
%                     "8i"	"1232"
%                     "8j"	"1233"
%                     "8k"	"1234"
%                     "8l"	"1235"
%                     "8m"	"1236"
%                     "8n"	"1237"
%                     "8o"	"1238"
%                     "8p"	"1239"
%                     "8q"	"1240"
%                     "8r"	"1241"
%                     "8s"	"1242"
%                     "8t"	"1243"
%                     "8u"	"1244"
%                     "8v"	"1245"
%                     "8w"	"1246"
%                     "8x"	"1247"
%                     "8y"	"1248"
%                     "8z"	"1249"
%                     "80"	"1250"
%                     "81"	"1251"
%                     "82"	"1252"
%                     "83"	"1253"
%                     "84"	"1254"
%                     "85"	"1255"
%                     "86"	"1256"
%                     "87"	"1257"
%                     "88"	"1258"
%                     "89"	"1259"
%                     "9a"	"1260"
%                     "9b"	"1261"
%                     "9c"	"1262"
%                     "9d"	"1263"
%                     "9e"	"1264"
%                     "9f"	"1265"
%                     "9g"	"1266"
%                     "9h"	"1267"
%                     "9i"	"1268"
%                     "9j"	"1269"
%                     "9k"	"1270"
%                     "9l"	"1271"
%                     "9m"	"1272"
%                     "9n"	"1273"
%                     "9o"	"1274"
%                     "9p"	"1275"
%                     "9q"	"1276"
%                     "9r"	"1277"
%                     "9s"	"1278"
%                     "9t"	"1279"
%                     "9u"	"1280"
%                     "9v"	"1281"
%                     "9w"	"1282"
%                     "9x"	"1283"
%                     "9y"	"1284"
%                     "9z"	"1285"
%                     "90"	"1286"
%                     "91"	"1287"
%                     "92"	"1288"
%                     "93"	"1289"
%                     "94"	"1290"
%                     "95"	"1291"
%                     "96"	"1292"
%                     "97"	"1293"
%                     "98"	"1294"
%                     "99"	"1295"
% ];
%             str1 = 'A' + char(str1) + '5';
%             newstr = "";
%             i1 = 1;
%             while i1 < length(str1) - 1
%                 k = 0;
%                 char2 = '';
%                 char2 = str1(i1:i1+1);
%                 j1 = 1;
%                 while j1 < length(lsj)
%                     if char2 == char(lsj(j1,1))
%                         k = 1;
%                         newstr = newstr + lsj(j1,2);
%                     end
%                     j1 = j1 + 1;
%                 end
%                 if k == 0
%                     newstr = newstr + char2;
%                 end
%                 i1 = i1 + 2;
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {737, 737};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {213, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: selectDist
        function selectDistButtonPushed(app, event)
            mainDir = uigetdir('D:\农房工程文件夹','选择文件夹'); % 资料整理_>村级编码
            app.subDir = dir(mainDir); %确定子文件夹
            app.subDir(1) = [];app.subDir(1) = []; %删除隐藏文件夹
            for i = length(app.subDir):-1:1
                if app.subDir(i).isdir == 0
                    app.subDir(i) = [];
                end
            end
            app.n = length(app.subDir); %户数
            app.dirPath.Text = mainDir;
            app.dirPath.Tooltip = mainDir;
            app.addDist(app.subDir);
        end

        % Button pushed function: Button02, Button03, Button08, 
        % Button11, Button13, Button99
        function updateName(app, event)
            % 清空命名规则
            buttonList1 = event.Source.Parent.Children;
            for i = 1:length(buttonList1)
                buttonList1(i).BackgroundColor = [0.96, 0.96, 0.96];
            end
            % 更新命名规则
            selectedButton = event.Source;
            selectedButton.BackgroundColor = [0.8, 0.8, 0.8];
            app.reTitle = selectedButton.Text;
            % 清空旋转规则
            app.angle = 0;
            buttonList2 = app.rotateRule.Children;
            for i = 1:length(buttonList2)
                buttonList2(i).BackgroundColor = [0.96, 0.96, 0.96];
            end
%             uiconfirm(app.UIFigure, ['命名规则：' app.reTitle newline '旋转规则：' num2str(app.angle) '°'], '提示', 'Options', {'确定'});
        end

        % Button pushed function: rotateLeft, rotateRight
        function updateRotate(app, event)
            % 清空旋转规则
            buttonList1 = event.Source.Parent.Children;
            for i = 1:length(buttonList1)
                buttonList1(i).BackgroundColor = [0.96, 0.96, 0.96];
            end
            % 更新旋转规则
            selectedButton = event.Source;
            selectedButton.BackgroundColor = [0.8, 0.8, 0.8];
            if selectedButton.Text(1) == '左'
                app.angle = 90;
            else
                app.angle = 270;
            end
            % 清空命名规则
            app.reTitle = '';
            buttonList2 = app.nameRule.Children;
            for i = 1:length(buttonList2)
                buttonList2(i).BackgroundColor = [0.96, 0.96, 0.96];
            end
%             uiconfirm(app.UIFigure, ['命名规则：' app.reTitle newline '旋转规则：' num2str(app.angle) '°'], '提示', 'Options', {'确定'});
        end

        % Button pushed function: save_last
        function save_lastButtonPushed(app, event)
            % 打开上一文件夹
            app.No = app.No - 1;
            if app.No < 1
                uiconfirm(app.UIFigure,'当前村第一户','提示','Options',{'确定'});
            else
                app.addDist(app.subDir);
            end
        end

        % Callback function: UIFigure, save_next
        function save_nextButtonPushed(app, event)
            % 打开下一文件夹
            app.No = app.No + 1;
            if app.No > app.n
                uiconfirm(app.UIFigure,'当前村最后一户','提示','Options',{'确定'});
            else
                app.addDist(app.subDir);
            end
        end

        % Button pushed function: goto_n
        function goto_nButtonPushed(app, event)
            % 打开第n个文件夹
            if length(app.input.Value{1}) == 5
                app.JC = app.input.Value{1};
                subDir1=struct2cell(app.subDir);
                for c = 1:length(subDir1(1,:))
                    re = strfind(subDir1(1,c),app.JC);
                    if ~isempty(re{1,1})
                        app.No = c;
                        app.addDist(app.subDir);
                        break
                    end
                end
%                 uiconfirm(app.UIFigure,'找不到，请重新输入5位预编号','提示','Options',{'确定'});
            else
%                 uiconfirm(app.UIFigure,'找不到，请重新输入5位预编号','提示','Options',{'确定'});
            end
        end

        % Button pushed function: descendDist
        function descendDistButtonPushed(app, event)
            subDir3=struct2cell(app.subDir);
            T=char(subDir3(3,:));
            T = datetime(T,'Format','dd-MM-yyyy HH:mm:ss');
            [~,index]=sort(T,'descend');
            app.subDir=app.subDir(index);
            app.No = 1;
            app.addDist(app.subDir);
        end

        % Image clicked function: Image1, Image10, Image11, 
        % Image12, Image13, Image14, Image15, Image16, Image17, 
        % Image18, Image19, Image2, Image20, Image3, Image4, 
        % Image5, Image6, Image7, Image8, Image9
        function Image1Clicked(app, event)
            % 点击图片，记录修改信息
            imgObj = event.Source;
            if strlength(app.reTitle) > 0
                app.reName(imgObj);
            end
            if app.angle > 0
                app.reRotate(imgObj);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @save_nextButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.selectDist = uibutton(app.LeftPanel, 'push');
            app.selectDist.ButtonPushedFcn = createCallbackFcn(app, @selectDistButtonPushed, true);
            app.selectDist.Text = '选择文件夹';
            app.dirPath = uilabel(app.LeftPanel);
            app.dirPath.Text = '';
            app.nameRule = uipanel(app.LeftPanel);
            app.nameRule.Title = '命名规则';
            app.Button02 = uibutton(app.nameRule, 'push');
            app.Button02.ButtonPushedFcn = createCallbackFcn(app, @updateName, true);
            app.Button02.Text = '02_身份证';
            app.Button03 = uibutton(app.nameRule, 'push');
            app.Button03.ButtonPushedFcn = createCallbackFcn(app, @updateName, true);
            app.Button03.Text = '03_户口本';
            app.Button08 = uibutton(app.nameRule, 'push');
            app.Button08.ButtonPushedFcn = createCallbackFcn(app, @updateName, true);
            app.Button08.Text = '08_农村村民个人建房建设用地许可证';
            app.Button11 = uibutton(app.nameRule, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @updateName, true);
            app.Button11.Text = '11_集体土地使用证';
            app.Button13 = uibutton(app.nameRule, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @updateName, true);
            app.Button13.Text = '13_现场照片';
            app.Button99 = uibutton(app.nameRule, 'push');
            app.Button99.ButtonPushedFcn = createCallbackFcn(app, @updateName, true);
            app.Button99.Text = '99_外业草图';
            app.rotateRule = uipanel(app.LeftPanel);
            app.rotateRule.Title = '旋转';
            app.rotateLeft = uibutton(app.rotateRule, 'push');
            app.rotateLeft.ButtonPushedFcn = createCallbackFcn(app, @updateRotate, true);
            app.rotateLeft.Text = '左转90';
            app.rotateRight = uibutton(app.rotateRule, 'push');
            app.rotateRight.ButtonPushedFcn = createCallbackFcn(app, @updateRotate, true);
            app.rotateRight.Text = '右转90';
            app.Goto00000 = uipanel(app.LeftPanel);
            app.Goto00000.Title = '输入5位预编号：';
            app.input = uitextarea(app.Goto00000);
            app.goto_n = uibutton(app.Goto00000, 'push');
            app.goto_n.ButtonPushedFcn = createCallbackFcn(app, @goto_nButtonPushed, true);
            app.goto_n.Text = '跳转';
            app.folderInfo = uipanel(app.LeftPanel);
            app.folderInfo.Title = '文件夹信息';
            app.dirName = uilabel(app.folderInfo);
            app.dirName.Text = '';
            app.dirInfo = uilabel(app.folderInfo);
            app.dirInfo.Text = '';
            app.save_last = uibutton(app.folderInfo, 'push');
            app.save_last.ButtonPushedFcn = createCallbackFcn(app, @save_lastButtonPushed, true);
            app.save_last.Text = '上一个文件夹';
            app.save_next = uibutton(app.folderInfo, 'push');
            app.save_next.ButtonPushedFcn = createCallbackFcn(app, @save_nextButtonPushed, true);
            app.save_next.Text = '下一个文件夹';
            app.descendDist = uibutton(app.folderInfo, 'push');
            app.descendDist.ButtonPushedFcn = createCallbackFcn(app, @descendDistButtonPushed, true);
            app.descendDist.Text = '文件夹按时间降序排列';
            app.RightPanel = uipanel(app.GridLayout);
            app.imgGrid = uigridlayout(app.RightPanel);
            app.Image1 = uiimage(app.imgGrid);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image1.Visible = 'off';
            app.Image2 = uiimage(app.imgGrid);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image2.Visible = 'off';
            app.Image3 = uiimage(app.imgGrid);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image3.Visible = 'off';
            app.Image4 = uiimage(app.imgGrid);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image4.Visible = 'off';
            app.Image5 = uiimage(app.imgGrid);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image5.Visible = 'off';
            app.Image6 = uiimage(app.imgGrid);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image6.Visible = 'off';
            app.Image7 = uiimage(app.imgGrid);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image7.Visible = 'off';
            app.Image8 = uiimage(app.imgGrid);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image8.Visible = 'off';
            app.Image9 = uiimage(app.imgGrid);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image9.Visible = 'off';
            app.Image10 = uiimage(app.imgGrid);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image10.Visible = 'off';
            app.Image11 = uiimage(app.imgGrid);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image11.Visible = 'off';
            app.Image12 = uiimage(app.imgGrid);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image12.Visible = 'off';
            app.Image13 = uiimage(app.imgGrid);
            app.Image13.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image13.Visible = 'off';
            app.Image14 = uiimage(app.imgGrid);
            app.Image14.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image14.Visible = 'off';
            app.Image15 = uiimage(app.imgGrid);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image15.Visible = 'off';
            app.Image16 = uiimage(app.imgGrid);
            app.Image16.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image16.Visible = 'off';
            app.Image17 = uiimage(app.imgGrid);
            app.Image17.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image17.Visible = 'off';
            app.Image18 = uiimage(app.imgGrid);
            app.Image18.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image18.Visible = 'off';
            app.Image19 = uiimage(app.imgGrid);
            app.Image19.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image19.Visible = 'off';
            app.Image20 = uiimage(app.imgGrid);
            app.Image20.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image20.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A5_rename_Qidong

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end